classdef EEGsonic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGSonicUIFigure             matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SaveSettingsMenu             matlab.ui.container.Menu
        QuitEEGsonicMenu             matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        GeneralTab                   matlab.ui.container.Tab
        SonificationTab              matlab.ui.container.Tab
        SpectralPowerRatioTab        matlab.ui.container.Tab
        TopographicdistributionTab   matlab.ui.container.Tab
        PhaseamplitudecouplingTab    matlab.ui.container.Tab
        FrontoparietalwPLITab        matlab.ui.container.Tab
        FrontoparietaldPLITab        matlab.ui.container.Tab
        HubLocationTab               matlab.ui.container.Tab
        PermutationEntropyTab        matlab.ui.container.Tab
        EEGSonicEGIeditionPanel      matlab.ui.container.Panel
        SelectSavingDirectoryButton  matlab.ui.control.Button
        SavingDirectoryLabel         matlab.ui.control.Label
        SonificateCheckBox           matlab.ui.control.CheckBox
        LaunchEEGsonicButton         matlab.ui.control.Button
    end


    properties (Access = private)
        % Strings
        start_message;
        exit_message;
        
        parameters;
        information;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Settinng up String
            app.start_message = "Welcome to EEGsonic, an application by the Biosignal Interaction and Personhood Technology (BIAPT) lab at McGill";
            app.exit_message = "Thank you for using EEGsonic! If there is an issue with the code please send a message to yacine.mahdid@mail.mcgill.ca or visit https://github.com/BIAPT/EEGsonic/issues to open an issue";
            
            % Setup
            base_directory = erase(mfilename('fullpath'),strcat('\',mfilename));
            
            disp(mfilename('fullpath'));
            % Setting up Variables
            app_data = load(strcat(base_directory,'/data/app_data'));
            app.parameters = app_data.parameters;
            app.information = app_data.information;
            app.information.base_directory = base_directory;
            
            % Welcoming the user
            clc;
            disp(app.start_message);
            
            % Populating the GUI with saved data
            app.SavingDirectoryLabel.Text = app.information.saving_directory;
            
        end

        % Menu selected function: QuitEEGsonicMenu
        function QuitEEGsonicMenuSelected(app, event)
            disp(app.exit_message);
            app.delete();
        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)
            disp('Saving Settings...');
            parameters = app.parameters;
            information = app.information;
            save(strcat(app.information.base_directory,'/data/app_data'),'parameters','-append');
            save(strcat(app.information.base_directory,'/data/app_data'),'information','-append');
            disp('Finished Saving!');
        end

        % Button pushed function: SelectSavingDirectoryButton
        function SelectSavingDirectoryButtonPushed(app, event)
            path = uigetdir;
            if(path ~= 0)
               app.information.saving_directory = path;
               app.SavingDirectoryLabel.Text = path;
               disp(['Updated saving directory to : ',path]);
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGSonicUIFigure = uifigure;
            app.EEGSonicUIFigure.Name = 'EEG Sonic';
            app.FileMenu = uimenu(app.EEGSonicUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveSettingsMenu = uimenu(app.FileMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.QuitEEGsonicMenu = uimenu(app.FileMenu);
            app.QuitEEGsonicMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitEEGsonicMenuSelected, true);
            app.QuitEEGsonicMenu.Text = 'Quit EEGsonic';
            app.TabGroup = uitabgroup(app.EEGSonicUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.SonificationTab = uitab(app.TabGroup);
            app.SonificationTab.Title = 'Sonification';
            app.SpectralPowerRatioTab = uitab(app.TabGroup);
            app.SpectralPowerRatioTab.Title = 'Spectral Power Ratio';
            app.TopographicdistributionTab = uitab(app.TabGroup);
            app.TopographicdistributionTab.Title = 'Topographic distribution';
            app.PhaseamplitudecouplingTab = uitab(app.TabGroup);
            app.PhaseamplitudecouplingTab.Title = 'Phase amplitude coupling';
            app.FrontoparietalwPLITab = uitab(app.TabGroup);
            app.FrontoparietalwPLITab.Title = 'Frontoparietal wPLI';
            app.FrontoparietaldPLITab = uitab(app.TabGroup);
            app.FrontoparietaldPLITab.Title = 'Frontoparietal dPLI';
            app.HubLocationTab = uitab(app.TabGroup);
            app.HubLocationTab.Title = 'Hub Location';
            app.PermutationEntropyTab = uitab(app.TabGroup);
            app.PermutationEntropyTab.Title = 'Permutation Entropy';
            app.EEGSonicEGIeditionPanel = uipanel(app.EEGSonicUIFigure);
            app.EEGSonicEGIeditionPanel.Title = 'EEGSonic (EGI edition)';
            app.SelectSavingDirectoryButton = uibutton(app.EEGSonicEGIeditionPanel, 'push');
            app.SelectSavingDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSavingDirectoryButtonPushed, true);
            app.SelectSavingDirectoryButton.Text = 'Select Saving Directory';
            app.SavingDirectoryLabel = uilabel(app.EEGSonicEGIeditionPanel);
            app.SavingDirectoryLabel.Text = 'No saving directory selected';
            app.SonificateCheckBox = uicheckbox(app.EEGSonicEGIeditionPanel);
            app.SonificateCheckBox.Text = 'Sonificate';
            app.LaunchEEGsonicButton = uibutton(app.EEGSonicEGIeditionPanel, 'push');
            app.LaunchEEGsonicButton.Text = 'Launch EEGsonic';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGsonic_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGSonicUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGSonicUIFigure)
        end
    end
end